classdef page4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page3
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            load(".\OmegaRe.mat"); % 计算得到的OmegaRe
            load('.\transMat.mat'); % a^k_ni
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\para.mat')
            order_max=10;% 需要修改
            a0=0; %敲击位置，(theta=0)
            Omega=Omega(1:order_max+1,1:order_max)/2;% 一致化
            %%
            r_num=100;
            theta_num=100;% 切分的数目
            z_num=100;
            r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
            theta=linspace(0,2*pi,theta_num);
            z(1,1,:)=linspace(0,L,z_num);
            % z0=zeros(r_num,theta_num);
            % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
            %%
            % 锥形初始条件
            h0=0.001;

            ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
            cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
            coscbo=-cosoba;
            cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
            z0=h0*cb./(cb+ab);
            z0(isnan(z0))=h0*1/(1+a0);
            % surfShape(z0,r,theta);
            %%
            w_nm= zeros(size(Omega));% 膜在膜的本征态上的展开系数的矩阵
            shape_m=cell(order_max+1,order_max);% 记录膜在腔的本征态下的形状的cell
            % r1=r-a/2/r_num;r2=r+a/2/r_num;
            % ds=pi*(r2.^2-r1.^2)/theta_num;
            ds=pi*2*r/r_num/theta_num;% 和上面两行等价
            ds=repmat(ds,1,theta_num);
            w_cm=zeros(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0 && mm==1
                        z1=sqrt(2)*ones(size(r))*cos(nn*theta)/sqrt(pi)/sqrt(2);
                    elseif nn==0
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi)/sqrt(2);
                    else%mm 需要+1
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi);
                    end
                    % 先是r,后是theta
                    shape_m{nn+1,mm}=z1;
                    w_cm(nn+1,mm)=sum(sum(z0.*z1.*ds));
                end
            end
            shape=zeros(r_num,theta_num);
            for nn=0:order_max
                for mm=1:order_max
                    shape=shape+shape_m{nn+1,mm}*w_cm(nn+1,mm);
                end
            end
            %膜的初态按照腔的本征态展开
            %%
            % 角标应为n m k
            w_cn=zeros(size(w_cm));
            for nn=0:order_max
                %     w_cn(nn+1,:)=((reshape(transMat(nn+1,:,:),order_max,order_max))^-1*w_cm(nn+1,1:order_max)')';
                w_cn(nn+1,:)=w_cm(nn+1,1:order_max)*reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            end
            %%

            fn=10000; % 横轴划分的个数
            omega_limit=10;
            x=linspace(0,omega_limit*ca/a/2/pi,fn);
            y=zeros(1,fn);
            for nn=0:order_max
                for mm=1:order_max
                    y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
                end
            end
            y=y/max(y);
            plot(app.UIAxes,x,y)
            xlim(app.UIAxes,[0 2e3])
            title(app.UIAxes,'频谱图')

            ii=0;
            for t=0:0.0001:0.02
                shape=zeros(r_num,theta_num);
                for nn=0:order_max
                    for mm=1:order_max
                        shape=shape+shape_m{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t);
                    end
                end
                try
                    surfShape(app.UIAxes2,shape,r,theta);
                    view(app.UIAxes2,-30,70);  % 设置视点位置
                    axis(app.UIAxes2,[-1,1,-1,1,-3*h0,3*h0]); % 显示不全调节最后两个参数
                    title(app.UIAxes2,t)
                catch
                    break
                end
                %     saveas(gcf,['./pic/' num2str(ii) '.jpg'])
                %                 disp(t)
                ii=ii+1;
                pause(0.1)
            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure)
            page5
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '上一步';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '下一步';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '开始计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end